classdef picco_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Hide3rdCheckBox              matlab.ui.control.CheckBox
        Hide2ndCheckBox              matlab.ui.control.CheckBox
        Tree_3                       matlab.ui.container.CheckBoxTree
        PiccovaluesNode_3            matlab.ui.container.TreeNode
        PCCONode_3                   matlab.ui.container.TreeNode
        HRNode_3                     matlab.ui.container.TreeNode
        APsNode_3                    matlab.ui.container.TreeNode
        APdNode_3                    matlab.ui.container.TreeNode
        APmNode_3                    matlab.ui.container.TreeNode
        CVPNode_3                    matlab.ui.container.TreeNode
        SVNode_3                     matlab.ui.container.TreeNode
        SVRNode_3                    matlab.ui.container.TreeNode
        DPMXNode_3                   matlab.ui.container.TreeNode
        CONode_3                     matlab.ui.container.TreeNode
        SVVNode_3                    matlab.ui.container.TreeNode
        PPVNode_3                    matlab.ui.container.TreeNode
        PCCINode_3                   matlab.ui.container.TreeNode
        SVINode_3                    matlab.ui.container.TreeNode
        SVRINode_3                   matlab.ui.container.TreeNode
        SVMinNode_3                  matlab.ui.container.TreeNode
        SVMaxNode_3                  matlab.ui.container.TreeNode
        Tree_2                       matlab.ui.container.CheckBoxTree
        PiccovaluesNode_2            matlab.ui.container.TreeNode
        PCCONode_2                   matlab.ui.container.TreeNode
        HRNode_2                     matlab.ui.container.TreeNode
        APsNode_2                    matlab.ui.container.TreeNode
        APdNode_2                    matlab.ui.container.TreeNode
        APmNode_2                    matlab.ui.container.TreeNode
        CVPNode_2                    matlab.ui.container.TreeNode
        SVNode_2                     matlab.ui.container.TreeNode
        SVRNode_2                    matlab.ui.container.TreeNode
        DPMXNode_2                   matlab.ui.container.TreeNode
        CONode_2                     matlab.ui.container.TreeNode
        SVVNode_2                    matlab.ui.container.TreeNode
        PPVNode_2                    matlab.ui.container.TreeNode
        PCCINode_2                   matlab.ui.container.TreeNode
        SVINode_2                    matlab.ui.container.TreeNode
        SVRINode_2                   matlab.ui.container.TreeNode
        SVMinNode_2                  matlab.ui.container.TreeNode
        SVMaxNode_2                  matlab.ui.container.TreeNode
        DrawButton                   matlab.ui.control.Button
        InputsourceDropDown          matlab.ui.control.DropDown
        InputsourceDropDownLabel     matlab.ui.control.Label
        EndrowindexEditField         matlab.ui.control.NumericEditField
        EndrowindexEditFieldLabel    matlab.ui.control.Label
        StartrowindexEditField       matlab.ui.control.NumericEditField
        StartrowindexEditFieldLabel  matlab.ui.control.Label
        Tree                         matlab.ui.container.CheckBoxTree
        PiccovaluesNode              matlab.ui.container.TreeNode
        PCCONode                     matlab.ui.container.TreeNode
        HRNode                       matlab.ui.container.TreeNode
        APsNode                      matlab.ui.container.TreeNode
        APdNode                      matlab.ui.container.TreeNode
        APmNode                      matlab.ui.container.TreeNode
        CVPNode                      matlab.ui.container.TreeNode
        SVNode                       matlab.ui.container.TreeNode
        SVRNode                      matlab.ui.container.TreeNode
        DPMXNode                     matlab.ui.container.TreeNode
        CONode                       matlab.ui.container.TreeNode
        SVVNode                      matlab.ui.container.TreeNode
        PPVNode                      matlab.ui.container.TreeNode
        PCCINode                     matlab.ui.container.TreeNode
        SVINode                      matlab.ui.container.TreeNode
        SVRINode                     matlab.ui.container.TreeNode
        SVMinNode                    matlab.ui.container.TreeNode
        SVMaxNode                    matlab.ui.container.TreeNode
        RightPanel                   matlab.ui.container.Panel
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        piccoTable % Tablazat, ahol a beolvasott ertekeket tartjuk
        startRowIndex % startRowIndex
        endRowIndex % endRowIndex
        error % error
        selectedPiccoValues % selectedPiccoValues
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.piccoTable = readtable('resources/data/picco_1.csv'); % xlsl fajl beolvasasa
            assignin('base', 'piccoTable', app.piccoTable)
            %             summary(app.piccoTable);
        end

        % Value changed function: InputsourceDropDown
        function InputsourceDropDownValueChanged(app, event)
            value = app.InputsourceDropDown.Value;
            switch value
                case 'picco0'
                    app.piccoTable = readtable('resources/data/picco_0.csv'); % xlsl fajl beolvasasa
                case 'picco1'
                    app.piccoTable = readtable('resources/data/picco_1.csv'); % xlsl fajl beolvasasa
                case 'picco2'
                    app.piccoTable = readtable('resources/data/picco_2.csv'); % xlsl fajl beolvasasa
                case 'picco3'
                    app.piccoTable = readtable('resources/data/picco_3.csv'); % xlsl fajl beolvasasa
            end
        end

        % Value changed function: StartrowindexEditField
        function StartrowindexEditFieldValueChanged(app, event)
            piccoTableHeight = height(app.piccoTable)
            if(app.StartrowindexEditField.Value > piccoTableHeight)
                app.error = errordlg('Start row index cant be greater then piccoTableLength','Input Error');
            else
                app.startRowIndex = app.StartrowindexEditField.Value;
            end
        end

        % Value changed function: EndrowindexEditField
        function EndrowindexEditFieldValueChanged(app, event)
            piccoTableHeight = height(app.piccoTable);
            if(app.EndrowindexEditField.Value > piccoTableHeight)
                app.error = errordlg('End row index cant be greater then piccoTableLength','Input Error');
            elseif(app.EndrowindexEditField.Value < app.startRowIndex)
                app.error = errordlg('Start row index cant be greater then end row index','Input Error');
            else
                app.endRowIndex = app.EndrowindexEditField.Value;
            end

        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            for i = 1:length(app.selectedPiccoValues)
                switch app.selectedPiccoValues(i).Text
                    case 'PCCO'
                        plot(app.UIAxes, app.piccoTable.PCCO(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& PCCO'))
                        hold(app.UIAxes,'on');
                    case 'HR'
                        plot(app.UIAxes, app.piccoTable.HR(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& HR'))
                        hold(app.UIAxes,'on');
                    case 'APs'
                        plot(app.UIAxes, app.piccoTable.APs(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& APs'))
                        hold(app.UIAxes,'on');
                    case 'APd'
                        plot(app.UIAxes, app.piccoTable.APd(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& APd'))
                        hold(app.UIAxes,'on');
                    case 'APm'
                        plot(app.UIAxes, app.piccoTable.APm(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& APm'))
                        hold(app.UIAxes,'on');
                    case 'CVP'
                        plot(app.UIAxes, app.piccoTable.CVP(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& CVP'))
                        hold(app.UIAxes,'on');
                end
            end
            hold(app.UIAxes,'off');
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.selectedPiccoValues = app.Tree.CheckedNodes;
            if(isempty(app.selectedPiccoValues))
                app.error = errordlg('You have to choose one picco value!','Input Error');
                set(app.DrawButton, 'Enable', 'off');
            else
                set(app.DrawButton, 'Enable', 'on');
            end
        end

        % Value changed function: Hide2ndCheckBox
        function Hide2ndCheckBoxValueChanged(app, event)
            value = app.Hide2ndCheckBox.Value;
            if(value == 1)
                set(app.UIAxes_2, 'Visible', 'off');
                set(app.Tree_2, 'Visible', 'off');
            else
                set(app.UIAxes_2, 'Visible', 'on');
                set(app.Tree_2, 'Visible', 'on');
            end

        end

        % Value changed function: Hide3rdCheckBox
        function Hide3rdCheckBoxValueChanged(app, event)
            value = app.Hide3rdCheckBox.Value;
            if(value == 1)
                set(app.UIAxes_3, 'Visible', 'off');
                set(app.Tree_3, 'Visible', 'off');
            else
                set(app.UIAxes_3, 'Visible', 'on');
                set(app.Tree_3, 'Visible', 'on');
            end            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {788, 788};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {349, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.PiccovaluesNode = uitreenode(app.Tree);
            app.PiccovaluesNode.Text = 'Picco values';
            app.PCCONode = uitreenode(app.PiccovaluesNode);
            app.PCCONode.Text = 'PCCO';
            app.HRNode = uitreenode(app.PiccovaluesNode);
            app.HRNode.Text = 'HR';
            app.APsNode = uitreenode(app.PiccovaluesNode);
            app.APsNode.Text = 'APs';
            app.APdNode = uitreenode(app.PiccovaluesNode);
            app.APdNode.Text = 'APd';
            app.APmNode = uitreenode(app.PiccovaluesNode);
            app.APmNode.Text = 'APm';
            app.CVPNode = uitreenode(app.PiccovaluesNode);
            app.CVPNode.Text = 'CVP';
            app.SVNode = uitreenode(app.PiccovaluesNode);
            app.SVNode.Text = 'SV';
            app.SVRNode = uitreenode(app.PiccovaluesNode);
            app.SVRNode.Text = 'SVR';
            app.DPMXNode = uitreenode(app.PiccovaluesNode);
            app.DPMXNode.Text = 'DPMX';
            app.CONode = uitreenode(app.PiccovaluesNode);
            app.CONode.Text = 'CO';
            app.SVVNode = uitreenode(app.PiccovaluesNode);
            app.SVVNode.Text = 'SVV';
            app.PPVNode = uitreenode(app.PiccovaluesNode);
            app.PPVNode.Text = 'PPV';
            app.PCCINode = uitreenode(app.PiccovaluesNode);
            app.PCCINode.Text = 'PCCI';
            app.SVINode = uitreenode(app.PiccovaluesNode);
            app.SVINode.Text = 'SVI';
            app.SVRINode = uitreenode(app.PiccovaluesNode);
            app.SVRINode.Text = 'SVRI';
            app.SVMinNode = uitreenode(app.PiccovaluesNode);
            app.SVMinNode.Text = 'SVMin';
            app.SVMaxNode = uitreenode(app.PiccovaluesNode);
            app.SVMaxNode.Text = 'SVMax';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.StartrowindexEditFieldLabel = uilabel(app.LeftPanel);
            app.StartrowindexEditFieldLabel.Text = 'Start row index';
            app.StartrowindexEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartrowindexEditField.ValueChangedFcn = createCallbackFcn(app, @StartrowindexEditFieldValueChanged, true);
            app.EndrowindexEditFieldLabel = uilabel(app.LeftPanel);
            app.EndrowindexEditFieldLabel.Text = 'End row index';
            app.EndrowindexEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndrowindexEditField.ValueChangedFcn = createCallbackFcn(app, @EndrowindexEditFieldValueChanged, true);
            app.InputsourceDropDownLabel = uilabel(app.LeftPanel);
            app.InputsourceDropDownLabel.Text = 'Input source';
            app.InputsourceDropDown = uidropdown(app.LeftPanel);
            app.InputsourceDropDown.Items = {'picco0', 'picco1', 'picco2', 'picco3'};
            app.InputsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @InputsourceDropDownValueChanged, true);
            app.InputsourceDropDown.Value = 'picco1';
            app.DrawButton = uibutton(app.LeftPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Enable = 'off';
            app.DrawButton.Text = 'Draw';
            app.Tree_2 = uitree(app.LeftPanel, 'checkbox');
            app.Tree_2.Visible = 'off';
            app.PiccovaluesNode_2 = uitreenode(app.Tree_2);
            app.PiccovaluesNode_2.Text = 'Picco values';
            app.PCCONode_2 = uitreenode(app.PiccovaluesNode_2);
            app.PCCONode_2.Text = 'PCCO';
            app.HRNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.HRNode_2.Text = 'HR';
            app.APsNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.APsNode_2.Text = 'APs';
            app.APdNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.APdNode_2.Text = 'APd';
            app.APmNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.APmNode_2.Text = 'APm';
            app.CVPNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.CVPNode_2.Text = 'CVP';
            app.SVNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.SVNode_2.Text = 'SV';
            app.SVRNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.SVRNode_2.Text = 'SVR';
            app.DPMXNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.DPMXNode_2.Text = 'DPMX';
            app.CONode_2 = uitreenode(app.PiccovaluesNode_2);
            app.CONode_2.Text = 'CO';
            app.SVVNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.SVVNode_2.Text = 'SVV';
            app.PPVNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.PPVNode_2.Text = 'PPV';
            app.PCCINode_2 = uitreenode(app.PiccovaluesNode_2);
            app.PCCINode_2.Text = 'PCCI';
            app.SVINode_2 = uitreenode(app.PiccovaluesNode_2);
            app.SVINode_2.Text = 'SVI';
            app.SVRINode_2 = uitreenode(app.PiccovaluesNode_2);
            app.SVRINode_2.Text = 'SVRI';
            app.SVMinNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.SVMinNode_2.Text = 'SVMin';
            app.SVMaxNode_2 = uitreenode(app.PiccovaluesNode_2);
            app.SVMaxNode_2.Text = 'SVMax';
            app.Tree_3 = uitree(app.LeftPanel, 'checkbox');
            app.Tree_3.Visible = 'off';
            app.PiccovaluesNode_3 = uitreenode(app.Tree_3);
            app.PiccovaluesNode_3.Text = 'Picco values';
            app.PCCONode_3 = uitreenode(app.PiccovaluesNode_3);
            app.PCCONode_3.Text = 'PCCO';
            app.HRNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.HRNode_3.Text = 'HR';
            app.APsNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.APsNode_3.Text = 'APs';
            app.APdNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.APdNode_3.Text = 'APd';
            app.APmNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.APmNode_3.Text = 'APm';
            app.CVPNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.CVPNode_3.Text = 'CVP';
            app.SVNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.SVNode_3.Text = 'SV';
            app.SVRNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.SVRNode_3.Text = 'SVR';
            app.DPMXNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.DPMXNode_3.Text = 'DPMX';
            app.CONode_3 = uitreenode(app.PiccovaluesNode_3);
            app.CONode_3.Text = 'CO';
            app.SVVNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.SVVNode_3.Text = 'SVV';
            app.PPVNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.PPVNode_3.Text = 'PPV';
            app.PCCINode_3 = uitreenode(app.PiccovaluesNode_3);
            app.PCCINode_3.Text = 'PCCI';
            app.SVINode_3 = uitreenode(app.PiccovaluesNode_3);
            app.SVINode_3.Text = 'SVI';
            app.SVRINode_3 = uitreenode(app.PiccovaluesNode_3);
            app.SVRINode_3.Text = 'SVRI';
            app.SVMinNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.SVMinNode_3.Text = 'SVMin';
            app.SVMaxNode_3 = uitreenode(app.PiccovaluesNode_3);
            app.SVMaxNode_3.Text = 'SVMax';
            app.Hide2ndCheckBox = uicheckbox(app.LeftPanel);
            app.Hide2ndCheckBox.ValueChangedFcn = createCallbackFcn(app, @Hide2ndCheckBoxValueChanged, true);
            app.Hide2ndCheckBox.Text = 'Hide 2nd';
            app.Hide2ndCheckBox.Value = true;
            app.Hide3rdCheckBox = uicheckbox(app.LeftPanel);
            app.Hide3rdCheckBox.ValueChangedFcn = createCallbackFcn(app, @Hide3rdCheckBoxValueChanged, true);
            app.Hide3rdCheckBox.Text = 'Hide 3rd';
            app.Hide3rdCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = picco_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end