classdef picco_simulation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StopsimulationButton      matlab.ui.control.Button
        Tree                      matlab.ui.container.CheckBoxTree
        PiccovaluesNode           matlab.ui.container.TreeNode
        PCCONode                  matlab.ui.container.TreeNode
        HRNode                    matlab.ui.container.TreeNode
        APsNode                   matlab.ui.container.TreeNode
        APdNode                   matlab.ui.container.TreeNode
        APmNode                   matlab.ui.container.TreeNode
        CVPNode                   matlab.ui.container.TreeNode
        SVNode                    matlab.ui.container.TreeNode
        SVRNode                   matlab.ui.container.TreeNode
        DPMXNode                  matlab.ui.container.TreeNode
        CONode                    matlab.ui.container.TreeNode
        SVVNode                   matlab.ui.container.TreeNode
        PPVNode                   matlab.ui.container.TreeNode
        PCCINode                  matlab.ui.container.TreeNode
        SVINode                   matlab.ui.container.TreeNode
        SVRINode                  matlab.ui.container.TreeNode
        SVMinNode                 matlab.ui.container.TreeNode
        SVMaxNode                 matlab.ui.container.TreeNode
        StartsimulationButton     matlab.ui.control.Button
        InputsourceDropDown       matlab.ui.control.DropDown
        InputsourceDropDownLabel  matlab.ui.control.Label
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        piccoTable % Tablazat, ahol a beolvasott ertekeket tartjuk
        piccoTableRowIndex = 0 % piccoTableRowIndex
        error % error
        selectedPiccoValues % selectedPiccoValues
        simulationStarted % simulationStarted
    end

    properties (Access = public)
        piccoTimer = 1 % piccoTimer
        piccoInterval = 300 % piccoInterval
    end

    methods (Access = public)
        function PiccoTimerCallback(app, obj, event)
            app.piccoTableRowIndex = app.piccoTableRowIndex + 1;
            for i = 1:length(app.selectedPiccoValues)
                switch(i)
                    case 1
                        app.DrawNextItemOnAxes(app.UIAxes,app.selectedPiccoValues(i).Text);
                        set(app.UIAxes, 'Visible', 'on');
                    case 2
                        app.DrawNextItemOnAxes(app.UIAxes_2,app.selectedPiccoValues(i).Text);
                        set(app.UIAxes_2, 'Visible', 'on');
                    case 3
                        app.DrawNextItemOnAxes(app.UIAxes_3,app.selectedPiccoValues(i).Text);
                        set(app.UIAxes_3, 'Visible', 'on');
                    case 4
                        app.DrawNextItemOnAxes(app.UIAxes_4,app.selectedPiccoValues(i).Text);
                        set(app.UIAxes_4, 'Visible', 'on');
                end
            end

        end

        function DrawNextItemOnAxes(app, selectedAxes, selectedPiccoValue)
            startRowIndex = 1+app.piccoTableRowIndex;
            endRowIndex = 300+app.piccoTableRowIndex;
            switch selectedPiccoValue
                case 'PCCO'
                    plot(selectedAxes, app.piccoTable.PCCO(startRowIndex:endRowIndex));
                case 'HR'
                    plot(selectedAxes, app.piccoTable.HR(startRowIndex:endRowIndex));
                case 'APs'
                    plot(selectedAxes, app.piccoTable.APs(startRowIndex:endRowIndex));
                case 'APd'
                    plot(selectedAxes, app.piccoTable.APd(startRowIndex:endRowIndex));
                case 'APm'
                    plot(selectedAxes, app.piccoTable.APm(startRowIndex:endRowIndex));
                case 'CVP'
                    plot(selectedAxes, app.piccoTable.CVP(startRowIndex:endRowIndex));
            end
        end

        function setUIAxesUnvisible(app)
            set(app.UIAxes, 'Visible', 'off');
            plot(app.UIAxes, (1:1));
            set(app.UIAxes_2, 'Visible', 'off');
            plot(app.UIAxes_2,(1:1));
            set(app.UIAxes_3, 'Visible', 'off');
            plot(app.UIAxes_3,(1:1));
            set(app.UIAxes_4, 'Visible', 'off');
            plot(app.UIAxes_4,(1:1));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.piccoTable = readtable('resources/data/picco_1.csv'); % xlsl fajl beolvasasa
            app.piccoTimer = timer('Period',1,'ExecutionMode','fixedSpacing',...
                'TasksToExecute', Inf);
            app.piccoTimer.TimerFcn = @app.PiccoTimerCallback; % Your Timer Callback
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.setUIAxesUnvisible;
            app.selectedPiccoValues = app.Tree.CheckedNodes;
            if(isempty(app.selectedPiccoValues))
                app.error = errordlg('You have to choose one picco value!','Input Error');
                set(app.StartsimulationButton, 'Enable', 'off');
            elseif (length(app.selectedPiccoValues) > 4)
                app.error = errordlg('You can only four elements choose!','Input Error');
                set(app.StartsimulationButton, 'Enable', 'off');
            else
                set(app.StartsimulationButton, 'Enable', 'on');
            end
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            start(app.piccoTimer);
            app.simulationStarted = true;
            set(app.StopsimulationButton, 'Enable', 'on');
        end

        % Value changed function: InputsourceDropDown
        function InputsourceDropDownValueChanged(app, event)
            value = app.InputsourceDropDown.Value;
            switch value
                case 'picco0'
                    app.piccoTable = readtable('resources/data/picco_0.csv'); % xlsl fajl beolvasasa
                case 'picco1'
                    app.piccoTable = readtable('resources/data/picco_1.csv'); % xlsl fajl beolvasasa
                case 'picco2'
                    app.piccoTable = readtable('resources/data/picco_2.csv'); % xlsl fajl beolvasasa
                case 'picco3'
                    app.piccoTable = readtable('resources/data/picco_3.csv'); % xlsl fajl beolvasasa
            end
        end

        % Button pushed function: StopsimulationButton
        function StopsimulationButtonPushed(app, event)
            if(app.simulationStarted == true)
                stop(app.piccoTimer)
            end
            set(app.StopsimulationButton, 'Enable', 'off');
            app.simulationStarted = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.Visible = 'off';
            app.InputsourceDropDownLabel = uilabel(app.UIFigure);
            app.InputsourceDropDownLabel.Text = 'Input source';
            app.InputsourceDropDown = uidropdown(app.UIFigure);
            app.InputsourceDropDown.Items = {'picco0', 'picco1', 'picco2', 'picco3'};
            app.InputsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @InputsourceDropDownValueChanged, true);
            app.InputsourceDropDown.Value = 'picco1';
            app.StartsimulationButton = uibutton(app.UIFigure, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.PiccovaluesNode = uitreenode(app.Tree);
            app.PiccovaluesNode.Text = 'Picco values';
            app.PCCONode = uitreenode(app.PiccovaluesNode);
            app.PCCONode.Text = 'PCCO';
            app.HRNode = uitreenode(app.PiccovaluesNode);
            app.HRNode.Text = 'HR';
            app.APsNode = uitreenode(app.PiccovaluesNode);
            app.APsNode.Text = 'APs';
            app.APdNode = uitreenode(app.PiccovaluesNode);
            app.APdNode.Text = 'APd';
            app.APmNode = uitreenode(app.PiccovaluesNode);
            app.APmNode.Text = 'APm';
            app.CVPNode = uitreenode(app.PiccovaluesNode);
            app.CVPNode.Text = 'CVP';
            app.SVNode = uitreenode(app.PiccovaluesNode);
            app.SVNode.Text = 'SV';
            app.SVRNode = uitreenode(app.PiccovaluesNode);
            app.SVRNode.Text = 'SVR';
            app.DPMXNode = uitreenode(app.PiccovaluesNode);
            app.DPMXNode.Text = 'DPMX';
            app.CONode = uitreenode(app.PiccovaluesNode);
            app.CONode.Text = 'CO';
            app.SVVNode = uitreenode(app.PiccovaluesNode);
            app.SVVNode.Text = 'SVV';
            app.PPVNode = uitreenode(app.PiccovaluesNode);
            app.PPVNode.Text = 'PPV';
            app.PCCINode = uitreenode(app.PiccovaluesNode);
            app.PCCINode.Text = 'PCCI';
            app.SVINode = uitreenode(app.PiccovaluesNode);
            app.SVINode.Text = 'SVI';
            app.SVRINode = uitreenode(app.PiccovaluesNode);
            app.SVRINode.Text = 'SVRI';
            app.SVMinNode = uitreenode(app.PiccovaluesNode);
            app.SVMinNode.Text = 'SVMin';
            app.SVMaxNode = uitreenode(app.PiccovaluesNode);
            app.SVMaxNode.Text = 'SVMax';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.StopsimulationButton = uibutton(app.UIFigure, 'push');
            app.StopsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopsimulationButtonPushed, true);
            app.StopsimulationButton.Enable = 'off';
            app.StopsimulationButton.Text = 'Stop simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = picco_simulation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end